classdef meander_execute_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ExportgridsmovieSwitch        matlab.ui.control.Switch
        ExportgridsmovieSwitchLabel   matlab.ui.control.Label
        RunmodelSwitch                matlab.ui.control.Switch
        RunmodelSwitchLabel           matlab.ui.control.Label
        SelectexistingButton          matlab.ui.control.Button
        CreatenewtrackbankmaterialButton  matlab.ui.control.Button
        CreatenewchannelonlyButton    matlab.ui.control.Button
        StatusseecommandwindowLabel   matlab.ui.control.Label
        Panel_2                       matlab.ui.container.Panel
        CreatenewchannelonlyButton_2  matlab.ui.control.Button
        CreatenewtrackbankmaterialButton_2  matlab.ui.control.Button
        EditField_2                   matlab.ui.control.EditField
        ExportparameterfileLabel      matlab.ui.control.Label
        SelectexistingButton_2        matlab.ui.control.Button
        Panel                         matlab.ui.container.Panel
        EditField                     matlab.ui.control.EditField
        ModelparameterfileLabel       matlab.ui.control.Label
        AboutButton                   matlab.ui.control.Button
        Label                         matlab.ui.control.Label
        CutbankModelforrivermeanderingwithheterogeneousbankmaterialsLabel  matlab.ui.control.Label
        ExecuteButton                 matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %%% Set file paths
            % Get path to highest-level directory (one above current)
            currentDir = pwd;
            if ispc
                slash = '\';
            else 
                slash = '/';
            end
            ind = strfind(currentDir,slash);
            directory2Above = currentDir(1:ind(end-1));
            addpath(genpath(directory2Above)) % Adds paths to all model directories
        end

        % Button pushed function: SelectexistingButton_2
        function SelectexistingButton_2Pushed(app, event)
            [modelParameterFile,modelParameterFilePath] = uigetfile('*.mat');
            app.EditField_2.Value = [modelParameterFilePath,modelParameterFile];
        end

        % Button pushed function: SelectexistingButton
        function SelectexistingButtonPushed(app, event)
            [exportParameterFile,exportParameterFilePath] = uigetfile('*.mat');
            app.EditField.Value = [exportParameterFilePath,exportParameterFile];
        end

        % Button pushed function: ExecuteButton
        function ExecuteButtonPushed(app, event)
            modelParameterFile = app.EditField.Value;
            exportParameterFile = app.EditField_2.Value;
            
            if strcmp(app.RunmodelSwitch.Value,'On')
                runModel = true;
            else
                runModel = false;
            end
                
            if strcmp(app.ExportgridsmovieSwitch.Value,'On')
                exportGridsMovie = true;
            else 
                exportGridsMovie = false;
            end
            
            
            if runModel && ~exportGridsMovie
                mode = 'runModel';
            elseif ~runModel && exportGridsMovie
                mode = 'exportGridsMovie';
            elseif runModel && exportGridsMovie
                mode = 'runModel_and_exportGridsMovie';
            end
                        
            try    
                meander_execute(modelParameterFile,exportParameterFile,mode);
                msgbox('Model run/file export successful.');
            catch
                msgbox('Model run and/or file export failed.');
            end
        end

        % Button pushed function: AboutButton
        function AboutButtonPushed(app, event)
            msgbox('By Ajay B. Limaye, University of Virginia. Code version 1.0.0. This code is subject to the included GNU General Public License v3.0. The code repository is maintained at GitHub (https://github.com/alimaye/Cutbank).');
        end

        % Button pushed function: CreatenewtrackbankmaterialButton
        function CreatenewtrackbankmaterialButtonPushed(app, event)
            modelParameterFile = modelParametersGUI;
            app.EditField.Value = modelParameterFile;
        end

        % Button pushed function: CreatenewtrackbankmaterialButton_2
        function CreatenewtrackbankmaterialButton_2Pushed(app, event)
            
            modelParameterFile = app.EditField.Value; % load the model parameter file from above
            % Execute exportParametersGUI and update field for parameter
            % file
            exportParameterFile = exportParametersGUI(modelParameterFile);
            app.EditField_2.Value = exportParameterFile;
        end

        % Button pushed function: CreatenewchannelonlyButton
        function CreatenewchannelonlyButtonPushed(app, event)
           modelParameterFile = modelParameters_channelOnlyGUI;
           app.EditField.Value = modelParameterFile;
        end

        % Button pushed function: CreatenewchannelonlyButton_2
        function CreatenewchannelonlyButton_2Pushed(app, event)
            modelParameterFile = app.EditField.Value; % load the model parameter file from above
            exportParameterFile = exportParameters_channelOnlyGUI(modelParameterFile);
            app.EditField_2.Value = exportParameterFile;
        end

        % Callback function
        function UseaboveButtonPushed(app, event)
            modelParameterFile = app.EditField.Value;
            exportParameterFile = exportParametersGUI(modelParameterFile);
            app.EditField_2.Value = exportParameterFile;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ExecuteButton = uibutton(app.UIFigure, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButtonPushed, true);
            app.ExecuteButton.Text = 'Execute';
            app.CutbankModelforrivermeanderingwithheterogeneousbankmaterialsLabel = uilabel(app.UIFigure);
            app.CutbankModelforrivermeanderingwithheterogeneousbankmaterialsLabel.Text = {'Cutbank: '; 'Model for river meandering with heterogeneous bank materials'; ''};
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.AboutButton = uibutton(app.UIFigure, 'push');
            app.AboutButton.ButtonPushedFcn = createCallbackFcn(app, @AboutButtonPushed, true);
            app.AboutButton.Text = 'About';
            app.Panel = uipanel(app.UIFigure);
            app.ModelparameterfileLabel = uilabel(app.Panel);
            app.ModelparameterfileLabel.Text = 'Model parameter file:';
            app.EditField = uieditfield(app.Panel, 'text');
            app.Panel_2 = uipanel(app.UIFigure);
            app.SelectexistingButton_2 = uibutton(app.Panel_2, 'push');
            app.SelectexistingButton_2.ButtonPushedFcn = createCallbackFcn(app, @SelectexistingButton_2Pushed, true);
            app.SelectexistingButton_2.Text = 'Select existing';
            app.ExportparameterfileLabel = uilabel(app.Panel_2);
            app.ExportparameterfileLabel.Text = 'Export parameter file:';
            app.EditField_2 = uieditfield(app.Panel_2, 'text');
            app.CreatenewtrackbankmaterialButton_2 = uibutton(app.Panel_2, 'push');
            app.CreatenewtrackbankmaterialButton_2.ButtonPushedFcn = createCallbackFcn(app, @CreatenewtrackbankmaterialButton_2Pushed, true);
            app.CreatenewtrackbankmaterialButton_2.Text = {'Create new'; '(track bank material)'};
            app.CreatenewchannelonlyButton_2 = uibutton(app.Panel_2, 'push');
            app.CreatenewchannelonlyButton_2.ButtonPushedFcn = createCallbackFcn(app, @CreatenewchannelonlyButton_2Pushed, true);
            app.CreatenewchannelonlyButton_2.Text = {'Create new'; '(channel only)'};
            app.StatusseecommandwindowLabel = uilabel(app.UIFigure);
            app.StatusseecommandwindowLabel.Text = 'Status: see command window';
            app.CreatenewchannelonlyButton = uibutton(app.UIFigure, 'push');
            app.CreatenewchannelonlyButton.ButtonPushedFcn = createCallbackFcn(app, @CreatenewchannelonlyButtonPushed, true);
            app.CreatenewchannelonlyButton.Text = {'Create new '; '(channel only)'};
            app.CreatenewtrackbankmaterialButton = uibutton(app.UIFigure, 'push');
            app.CreatenewtrackbankmaterialButton.ButtonPushedFcn = createCallbackFcn(app, @CreatenewtrackbankmaterialButtonPushed, true);
            app.CreatenewtrackbankmaterialButton.Text = {'Create new'; '(track bank material)'};
            app.SelectexistingButton = uibutton(app.UIFigure, 'push');
            app.SelectexistingButton.ButtonPushedFcn = createCallbackFcn(app, @SelectexistingButtonPushed, true);
            app.SelectexistingButton.Text = 'Select existing';
            app.RunmodelSwitchLabel = uilabel(app.UIFigure);
            app.RunmodelSwitchLabel.Text = 'Run model';
            app.RunmodelSwitch = uiswitch(app.UIFigure, 'slider');
            app.RunmodelSwitch.Value = 'On';
            app.ExportgridsmovieSwitchLabel = uilabel(app.UIFigure);
            app.ExportgridsmovieSwitchLabel.Text = 'Export grids/movie';
            app.ExportgridsmovieSwitch = uiswitch(app.UIFigure, 'slider');
            app.ExportgridsmovieSwitch.Value = 'On';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = meander_execute_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end